classdef dockECDExport < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        GridLayout              matlab.ui.container.GridLayout
        btnOK                   matlab.ui.control.Button
        Tree                    matlab.ui.container.CheckBoxTree
        GeneralAspects          matlab.ui.container.TreeNode
        x0000                   matlab.ui.container.TreeNode
        x9900                   matlab.ui.container.TreeNode
        xI010                   matlab.ui.container.TreeNode
        xI030                   matlab.ui.container.TreeNode
        xI050                   matlab.ui.container.TreeNode
        xI075                   matlab.ui.container.TreeNode
        AccountBook             matlab.ui.container.TreeNode
        xI050_I051_I052         matlab.ui.container.TreeNode
        AccountDescription      matlab.ui.container.TreeNode
        AccountSummary          matlab.ui.container.TreeNode
        xI150_I155              matlab.ui.container.TreeNode
        xI200_I250              matlab.ui.container.TreeNode
        xI350_I355              matlab.ui.container.TreeNode
        ApuracaoGeral           matlab.ui.container.TreeNode
        ApuracaoInterconexao    matlab.ui.container.TreeNode
        SummaryGeneral          matlab.ui.container.TreeNode
        SummaryResults          matlab.ui.container.TreeNode
        ConciliacaoGeral        matlab.ui.container.TreeNode
        ConcilicaoInterconexao  matlab.ui.container.TreeNode
        ContasAnotacao          matlab.ui.container.TreeNode
        RTFFiles                matlab.ui.container.TreeNode
        eFiscalizaLabel         matlab.ui.control.Label
    end

    
    properties (Access = private)
        %-----------------------------------------------------------------%
        Role = 'secondaryDockApp'
    end


    properties (Access = public)
        %-----------------------------------------------------------------%
        Container
        isDocked = true        
        mainApp
        callingApp
    end


    properties (Access = private)
        %-----------------------------------------------------------------%
        inputArgs
    end


    methods (Access = private)
        %-----------------------------------------------------------------%
        function updateTree(app, index)
            % selectedECD = app.mainApp.ecdObj(index);
            % 
            % if ~isfield(selectedECD.GUI, 'tableIds') || isempty(selectedECD.GUI.tableIds)
            %     update(selectedECD, 'GUI.TableIds', [], app.mainApp.General)
            % end
            % sheetsSorted = selectedECD.GUI.tableIds;
            % 
            % tags = 

            expand(app.Tree, 'all')
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainApp, callingApp, context, index)
            
            try
                appEngine.boot(app, app.Role, mainApp, callingApp)

                app.inputArgs = struct('context', context, 'index', index);
                updateTree(app, index)
                
            catch ME
                ui.Dialog(app.UIFigure, 'error', getReport(ME), 'CloseFcn', @(~,~)closeFcn(app));
            end
            
        end

        % Close request function: UIFigure
        function closeFcn(app, event)
            
            delete(app)
            
        end

        % Button pushed function: btnOK
        function btnOKButtonPushed(app, event)
            
            if isempty(app.Tree.CheckedNodes)
                app.btnOK.Enable = "off";
                return
            end

            context = app.inputArgs.context;
            index = app.inputArgs.index;

            tableIdFields = {app.Tree.CheckedNodes.Tag};
            tableIdFields(cellfun(@(x) isempty(x), tableIdFields)) = [];

            ipcMainMatlabCallsHandler(app.mainApp, app, 'onExportECD', context, index, tableIdFields)

        end

        % Callback function: Tree
        function TreeCheckedNodesChanged(app, event)
            
            app.btnOK.Enable = ~isempty(app.Tree.CheckedNodes);
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'monitorSPED';
            app.UIFigure.Icon = 'icon_16.png';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @closeFcn, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.eFiscalizaLabel = uilabel(app.GridLayout);
            app.eFiscalizaLabel.Text = 'INFORMAÇÃO A EXPORTAR';
            app.Tree = uitree(app.GridLayout, 'checkbox');
            app.GeneralAspects = uitreenode(app.Tree);
            app.GeneralAspects.Text = 'Aspectos gerais';
            app.x0000 = uitreenode(app.GeneralAspects);
            app.x0000.Tag = 'x0000';
            app.x0000.Text = '0000';
            app.x9900 = uitreenode(app.GeneralAspects);
            app.x9900.Tag = 'x9900';
            app.x9900.Text = '9900';
            app.xI010 = uitreenode(app.GeneralAspects);
            app.xI010.Tag = 'xI010';
            app.xI010.Text = 'I010';
            app.xI030 = uitreenode(app.GeneralAspects);
            app.xI030.Tag = 'xI030';
            app.xI030.Text = 'I030';
            app.xI050 = uitreenode(app.GeneralAspects);
            app.xI050.Tag = 'xI050';
            app.xI050.Text = 'I050';
            app.xI075 = uitreenode(app.GeneralAspects);
            app.xI075.Tag = 'xI075';
            app.xI075.Text = 'I075';
            app.AccountBook = uitreenode(app.Tree);
            app.AccountBook.Text = 'Plano de contas';
            app.xI050_I051_I052 = uitreenode(app.AccountBook);
            app.xI050_I051_I052.Tag = 'xI050_I051_I052';
            app.xI050_I051_I052.Text = 'I050_I051_I052';
            app.AccountDescription = uitreenode(app.AccountBook);
            app.AccountDescription.Tag = 'x_CONTAS_DESCRICAO';
            app.AccountDescription.Text = 'Descrição completa';
            app.AccountSummary = uitreenode(app.Tree);
            app.AccountSummary.Text = 'Balancetes e tabelas de apuração';
            app.xI150_I155 = uitreenode(app.AccountSummary);
            app.xI150_I155.Tag = 'xI150_I155';
            app.xI150_I155.Text = 'I150_I155';
            app.xI200_I250 = uitreenode(app.AccountSummary);
            app.xI200_I250.Tag = 'xI200_I250';
            app.xI200_I250.Text = 'I200_I250';
            app.xI350_I355 = uitreenode(app.AccountSummary);
            app.xI350_I355.Tag = 'xI350_I355';
            app.xI350_I355.Text = 'I350_I355';
            app.ApuracaoGeral = uitreenode(app.AccountSummary);
            app.ApuracaoGeral.Tag = 'x_APURACAO_GERAL';
            app.ApuracaoGeral.Text = 'Apuração Geral';
            app.ApuracaoInterconexao = uitreenode(app.AccountSummary);
            app.ApuracaoInterconexao.Tag = 'x_APURACAO_INTERCONEXAO';
            app.ApuracaoInterconexao.Text = 'Apuração Interconexão';
            app.SummaryGeneral = uitreenode(app.AccountSummary);
            app.SummaryGeneral.Tag = 'x_BALANCETE_GERAL';
            app.SummaryGeneral.Text = 'Balancete Geral';
            app.SummaryResults = uitreenode(app.AccountSummary);
            app.SummaryResults.Tag = 'x_BALANCETE_RESULTADO';
            app.SummaryResults.Text = 'Balancete Resultado';
            app.ConciliacaoGeral = uitreenode(app.AccountSummary);
            app.ConciliacaoGeral.Tag = 'x_CONCILIACAO_GERAL';
            app.ConciliacaoGeral.Text = 'Conciliação Geral';
            app.ConcilicaoInterconexao = uitreenode(app.AccountSummary);
            app.ConcilicaoInterconexao.Tag = 'x_CONCILIACAO_INTERCONEXAO';
            app.ConcilicaoInterconexao.Text = 'Conciliação Interconexão';
            app.ContasAnotacao = uitreenode(app.AccountSummary);
            app.ContasAnotacao.Tag = 'x_CONTAS_ANOTACAO';
            app.ContasAnotacao.Text = 'Contas Anotação';
            app.RTFFiles = uitreenode(app.Tree);
            app.RTFFiles.Tag = 'xJ800|xJ801';
            app.RTFFiles.Text = 'Arquivos anexos .rtf (J800 e J801)';
            % Assign Checked Nodes
            app.Tree.CheckedNodes = [app.x0000, app.SummaryResults, app.ContasAnotacao, app.ApuracaoGeral, app.ApuracaoInterconexao, app.ConciliacaoGeral, app.ConcilicaoInterconexao];
            % Assign Checked Nodes
            app.Tree.CheckedNodesChangedFcn = createCallbackFcn(app, @TreeCheckedNodesChanged, true);
            app.btnOK = uibutton(app.GridLayout, 'push');
            app.btnOK.ButtonPushedFcn = createCallbackFcn(app, @btnOKButtonPushed, true);
            app.btnOK.Tag = 'OK';
            app.btnOK.Text = 'OK';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = dockECDExport(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end