classdef dockECDMemoryUsage < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure    matlab.ui.Figure
        GridLayout  matlab.ui.container.GridLayout
        UITable     matlab.ui.control.Table
        TreeLabel   matlab.ui.control.Label
    end

    
    properties (Access = private)
        %-----------------------------------------------------------------%
        Role = 'secondaryDockApp'
        Context
    end


    properties (Access = public)
        %-----------------------------------------------------------------%
        Container
        isDocked = true        
        mainApp
        callingApp
    end


    properties (Access = private)
        %-----------------------------------------------------------------%
        inputArgs
        spedObj
        appHandleNameInBase
    end


    methods
        %-----------------------------------------------------------------%
        function deleteSelectedTables(app)
            selectedRow = app.UITable.Selection;

            if ~isempty(selectedRow)
                tableId = app.UITable.Data.("REGISTRO"){selectedRow};
                
                if ~ismember(tableId, app.mainApp.General.context.(app.Context).cacheTables)
                    index = app.inputArgs.index;
                    ipcMainMatlabCallsHandler(app.mainApp, app, 'onCacheCleanup', index, {tableId})

                    updateTable(app, index)
                end
            end
        end
    end


    methods (Access = private)
        %-----------------------------------------------------------------%
        function updateTable(app, index)
            t = table( ...
                'Size', [0, 3], ...
                'VariableNames', {'REGISTRO', 'TAMANHO', 'OPERAÇÃO'}, ...
                'VariableTypes', {'cell', 'cell', 'cell'} ...
            );

            tableIdList = sort(extractAfter(fieldnames(app.spedObj(index).Table), 'x'));

            for ii = 1:numel(tableIdList)
                tableId   = tableIdList{ii};
                tableData = app.spedObj(index).Table.(['x' tableId]);
                tableInfo = whos('tableData');

                if ismember(tableId, app.mainApp.General.context.(app.Context).cacheTables)
                    deletableRowsText = '🔒︎';
                else
                    deletableRowsText = sprintf('<a href="matlab:evalin(''base'', ''deleteSelectedTables(%s)'')">❌</a>', app.appHandleNameInBase);
                end

                t(end+1, :) = {tableId, textFormatGUI.bytes2human(tableInfo.bytes), deletableRowsText};
            end

            app.UITable.Data = t;
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainApp, callingApp, context, index)
            
            try
                appEngine.boot(app, app.Role, mainApp, callingApp)

                app.inputArgs = struct('context', context, 'index', index);
                app.Context = context;
                switch context
                    case 'ECD'
                        app.spedObj = mainApp.ecdObj;
                    otherwise % 'EFD'
                        app.spedObj = mainApp.efdObj;
                end

                % Registra handle deste app no workspace "base", o que possibilita 
                % excluir registros de tabelas por meio de cliques na uitable.
                app.appHandleNameInBase = ui.Table.exportAppHandleToBaseWorkspace(app);

                % Customiza uitable, atualizando-se em seguida.
                addStyle(app.UITable, uistyle("Interpreter", "html"));
                addStyle(app.UITable, uistyle("HorizontalAlignment", "left"),   "column", 1);
                addStyle(app.UITable, uistyle("HorizontalAlignment", "right"),  "column", 2);
                addStyle(app.UITable, uistyle("HorizontalAlignment", "center"), "column", 3);
                
                updateTable(app, index)
                
            catch ME
                ui.Dialog(app.UIFigure, 'error', getReport(ME), 'CloseFcn', @(~,~)closeFcn(app));
            end
            
        end

        % Close request function: UIFigure
        function closeFcn(app, event)
            
            ui.Table.deleteAppHandleFromBaseWorkspace(app.appHandleNameInBase)            
            delete(app)
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'monitorSPED';
            app.UIFigure.Icon = 'icon_16.png';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @closeFcn, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.TreeLabel = uilabel(app.GridLayout);
            app.TreeLabel.Text = 'ESPAÇO EM MEMÓRIA OCUPADO POR REGISTROS LIDOS OU CRIADOS';
            app.UITable = uitable(app.GridLayout);
            app.UITable.ColumnName = {'REGISTRO'; ''; ''};
            app.UITable.RowName = {};
            app.UITable.SelectionType = 'row';
            app.UITable.Multiselect = 'off';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = dockECDMemoryUsage(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end