classdef winConfig < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        GridLayout                     matlab.ui.container.GridLayout
        dockModuleGrid                 matlab.ui.container.GridLayout
        dockModule_Undock              matlab.ui.control.Image
        dockModule_Close               matlab.ui.control.Image
        GridLayout2                    matlab.ui.container.GridLayout
        tool_simulationMode            matlab.ui.control.Image
        openDevTools                   matlab.ui.control.Image
        TabGroup                       matlab.ui.container.TabGroup
        ASPECTOSGERAISTab              matlab.ui.container.Tab
        General_Grid                   matlab.ui.container.GridLayout
        versionInfoRefresh             matlab.ui.control.Image
        AMBIENTELabel                  matlab.ui.control.Label
        openAuxiliarApp2Debug          matlab.ui.control.CheckBox
        openAuxiliarAppAsDocked        matlab.ui.control.CheckBox
        versionInfo                    matlab.ui.control.Label
        ANLISETab                      matlab.ui.container.Tab
        GridLayout_3                   matlab.ui.container.GridLayout
        Panel_2                        matlab.ui.container.Panel
        GridLayout4                    matlab.ui.container.GridLayout
        fontstylefontsize32pxfontTRABALHOEMANDAMENTOLabel  matlab.ui.control.Label
        PROCESSODEANLISEDOSDADOSLabel  matlab.ui.control.Label
        Panel                          matlab.ui.container.Panel
        GridLayout3                    matlab.ui.container.GridLayout
        Encoding                       matlab.ui.control.DropDown
        EncodingLabel                  matlab.ui.control.Label
        CheckStatus                    matlab.ui.control.DropDown
        CheckStatusLabel               matlab.ui.control.Label
        SortMethod                     matlab.ui.control.DropDown
        SortMethodLabel                matlab.ui.control.Label
        InputType                      matlab.ui.control.DropDown
        InputTypeLabel                 matlab.ui.control.Label
        PROCESSODELEITURADOSARQUIVOSEVISUALIZAODOSSEUSMETADADOSLabel  matlab.ui.control.Label
        MAPEAMENTODEPASTASTab          matlab.ui.container.Tab
        FolderMapGrid                  matlab.ui.container.GridLayout
        userPathButton                 matlab.ui.control.Image
        userPath                       matlab.ui.control.EditField
        userPathLabel                  matlab.ui.control.Label
        DataHubPOSTButton              matlab.ui.control.Image
        DataHubPOST                    matlab.ui.control.EditField
        DATAHUBPOSTLabel               matlab.ui.control.Label
    end

    
    properties
        %-----------------------------------------------------------------%
        Container
        isDocked = false
        
        mainApp

        % A função do timer é executada uma única vez após a renderização
        % da figura, lendo arquivos de configuração, iniciando modo de operação
        % paralelo etc. A ideia é deixar o MATLAB focar apenas na criação dos 
        % componentes essenciais da GUI (especificados em "createComponents"), 
        % mostrando a GUI para o usuário o mais rápido possível.
        timerObj
        jsBackDoor

        % Janela de progresso já criada no DOM. Dessa forma, controla-se 
        % apenas a sua visibilidade - e tornando desnecessário criá-la a
        % cada chamada (usando uiprogressdlg, por exemplo).
        progressDialog
    end


    methods
        %-----------------------------------------------------------------%
        % IPC: COMUNICAÇÃO ENTRE PROCESSOS
        %-----------------------------------------------------------------%
        function ipcSecundaryJSEventsHandler(app, event)
            try
                switch event.HTMLEventName
                    case 'renderer'
                        startup_Controller(app)
                        
                    otherwise
                        error('UnexpectedEvent')
                end

            catch ME
                appUtil.modalWindow(app.UIFigure, 'error', ME.message);
            end
        end
    end
    

    methods (Access = private)
        %-----------------------------------------------------------------%
        % JSBACKDOOR
        %-----------------------------------------------------------------%
        function jsBackDoor_Initialization(app)
            app.jsBackDoor = uihtml(app.UIFigure, "HTMLSource",           appUtil.jsBackDoorHTMLSource(),                 ...
                                                  "HTMLEventReceivedFcn", @(~, evt)ipcSecundaryJSEventsHandler(app, evt), ...
                                                  "Visible",              "off");
        end

        %-----------------------------------------------------------------%
        function jsBackDoor_Customizations(app, tabIndex)
            persistent customizationStatus
            if isempty(customizationStatus)
                customizationStatus = [false, false, false];
            end

            switch tabIndex
                case 0 % STARTUP
                    if app.isDocked
                        app.progressDialog = app.mainApp.progressDialog;
                    else
                        sendEventToHTMLSource(app.jsBackDoor, 'startup', app.mainApp.executionMode);
                        app.progressDialog = ccTools.ProgressDialog(app.jsBackDoor);
                    end
                    customizationStatus = [false, false, false];

                otherwise
                    if customizationStatus(tabIndex)
                        return
                    end

                    customizationStatus(tabIndex) = true;
                    switch tabIndex
                        case 1
                            appName = class(app);

                            % Grid botões "dock":
                            if app.isDocked
                                elToModify = {app.dockModuleGrid};
                                elDataTag  = ui.CustomizationBase.getElementsDataTag(elToModify);
                                if ~isempty(elDataTag)
                                    sendEventToHTMLSource(app.jsBackDoor, 'initializeComponents', { ...
                                        struct('appName', appName, 'dataTag', elDataTag{1}, 'style', struct('transition', 'opacity 2s ease', 'opacity', '0.5')), ...
                                    });
                                end
                            end
                            
                            % Outros elementos:
                            elToModify = {app.versionInfo};
                            elDataTag  = ui.CustomizationBase.getElementsDataTag(elToModify);
                            if ~isempty(elDataTag)
                                ui.TextView.startup(app.jsBackDoor, app.versionInfo, appName);
                            end

                        case 2
                            Analysis_updatePanel(app)

                        case 3
                            Folder_updatePanel(app)
                    end
            end
        end
    end


    methods (Access = private)
        %-----------------------------------------------------------------%
        function startup_timerCreation(app)
            app.timerObj = timer("ExecutionMode", "fixedSpacing", ...
                                 "StartDelay",    1.5,            ...
                                 "Period",        .1,             ...
                                 "TimerFcn",      @(~,~)app.startup_timerFcn);
            start(app.timerObj)
        end

        %-----------------------------------------------------------------%
        function startup_timerFcn(app)
            if ccTools.fcn.UIFigureRenderStatus(app.UIFigure)
                stop(app.timerObj)
                delete(app.timerObj)
                
                jsBackDoor_Initialization(app)
            end
        end

        %-----------------------------------------------------------------%
        function startup_Controller(app)
            drawnow
            jsBackDoor_Customizations(app, 0)
            jsBackDoor_Customizations(app, 1)

            startup_GUIComponents(app)
        end

        %-----------------------------------------------------------------%
        function startup_GUIComponents(app)
            if ~strcmp(app.mainApp.executionMode, 'webApp')
                app.dockModule_Undock.Enable = 1;
                app.openDevTools.Enable = 1;

                set([app.DataHubPOSTButton, app.userPathButton], 'Enable', 1)
                app.versionInfoRefresh.Enable      = 1;
                app.openAuxiliarAppAsDocked.Enable = 1;
            end

            if ~isdeployed
                app.openAuxiliarApp2Debug.Enable = 1;
            end

            General_updatePanel(app)
        end

        %-----------------------------------------------------------------%
        function General_updatePanel(app)
            % Versão:
            ui.TextView.update(app.versionInfo, util.HtmlTextGenerator.AppInfo(app.mainApp.General, app.mainApp.rootFolder, app.mainApp.executionMode));

            % Modo de operação:
            app.openAuxiliarAppAsDocked.Value   = app.mainApp.General.operationMode.Dock;
            app.openAuxiliarApp2Debug.Value     = app.mainApp.General.operationMode.Debug;
        end

        %-----------------------------------------------------------------%
        function Analysis_updatePanel(app)
            app.InputType.Value = app.mainApp.General.sped.input;
            app.SortMethod.Value = app.mainApp.General.sped.sortMethod;
            app.Encoding.Items = app.mainApp.General.sped.encoding.options;            
        end

        %-----------------------------------------------------------------%
        function Folder_updatePanel(app)
            DataHub_POST = app.mainApp.General.fileFolder.DataHub_POST;    
            if isfolder(DataHub_POST)
                app.DataHubPOST.Value = DataHub_POST;
            end

            app.userPath.Value = app.mainApp.General.fileFolder.userPath;                
        end

        %-----------------------------------------------------------------%
        function saveGeneralSettings(app)
            appUtil.generalSettingsSave(class.Constants.appName, app.mainApp.rootFolder, app.mainApp.General_I, app.mainApp.executionMode)
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainApp)
            
            app.mainApp = mainApp;

            if app.isDocked
                app.GridLayout.Padding(4) = 30;
                app.dockModuleGrid.Visible = 1;
                app.jsBackDoor = mainApp.jsBackDoor;
                startup_Controller(app)
            else
                appUtil.winPosition(app.UIFigure)
                startup_timerCreation(app)
            end
            
        end

        % Close request function: UIFigure
        function closeFcn(app, event)
            
            ipcMainMatlabCallsHandler(app.mainApp, app, 'closeFcn')
            delete(app)
            
        end

        % Image clicked function: dockModule_Close, dockModule_Undock
        function DockModuleGroup_ButtonPushed(app, event)
            
            [idx, auxAppTag, relatedButton] = getAppInfoFromHandle(app.mainApp.tabGroupController, app);

            switch event.Source
                case app.dockModule_Undock
                    appGeneral = app.mainApp.General;
                    appGeneral.operationMode.Dock = false;
                    
                    app.mainApp.tabGroupController.Components.appHandle{idx} = [];

                    inputArguments = ipcMainMatlabCallsHandler(app.mainApp, app, 'dockButtonPushed', auxAppTag);
                    openModule(app.mainApp.tabGroupController, relatedButton, false, appGeneral, inputArguments{:})
                    closeModule(app.mainApp.tabGroupController, auxAppTag, app.mainApp.General, 'undock')
                    
                    delete(app)

                case app.dockModule_Close
                    closeModule(app.mainApp.tabGroupController, auxAppTag, app.mainApp.General)
            end

        end

        % Selection change function: TabGroup
        function TabGroup_TabSelectionChanged(app, event)
            
            [~, tabIndex] = ismember(app.TabGroup.SelectedTab, app.TabGroup.Children);
            jsBackDoor_Customizations(app, tabIndex)
            
        end

        % Image clicked function: versionInfoRefresh
        function Toolbar_AppEnvRefreshButtonPushed(app, event)
            
            app.progressDialog.Visible = 'visible';

            htmlContent = util.HtmlTextGenerator.checkUpdate(app.mainApp.General, app.mainApp.rootFolder);
            appUtil.modalWindow(app.UIFigure, "info", htmlContent);       

            app.progressDialog.Visible = 'hidden';

        end

        % Image clicked function: tool_simulationMode
        function Toolbar_SimulationModeButtonPushed(app, event)
            
            msgQuestion   = 'Deseja abrir os arquivos de <b>simulação</b>?';
            userSelection = appUtil.modalWindow(app.UIFigure, 'uiconfirm', msgQuestion, {'Sim', 'Não'}, 2, 2);
            
            if strcmp(userSelection, 'Não')
                return
            end

            app.mainApp.General.operationMode.Simulation = true;
            ipcMainMatlabCallsHandler(app.mainApp, app, 'simulationModeChanged')

        end

        % Image clicked function: openDevTools
        function Toolbar_OpenDevToolsClicked(app, event)
            
            ipcMainMatlabCallsHandler(app.mainApp, app, 'openDevTools')

        end

        % Value changed function: CheckStatus, Encoding, InputType, 
        % ...and 3 other components
        function Config_ParameterValueChanged(app, event)
            
            switch event.Source
                case app.openAuxiliarAppAsDocked
                    app.mainApp.General.operationMode.Dock  = app.openAuxiliarAppAsDocked.Value;

                case app.openAuxiliarApp2Debug
                    app.mainApp.General.operationMode.Debug = app.openAuxiliarApp2Debug.Value;

                case app.InputType
                    app.mainApp.General.sped.input          = app.InputType.Value;

                case app.SortMethod
                    app.mainApp.General.sped.sortMethod     = app.SortMethod.Value;
                    ipcMainMatlabCallsHandler(app.mainApp, app, 'fileSortMethodChanged')

                case app.CheckStatus
                    app.mainApp.General.sped.checkStatus    = app.CheckStatus.Value;

                case app.Encoding
                    app.mainApp.General.sped.encoding.value = app.Encoding.Value;
            end

            app.mainApp.General_I.operationMode = app.mainApp.General.operationMode;
            app.mainApp.General_I.sped          = app.mainApp.General.sped;
            saveGeneralSettings(app)

        end

        % Image clicked function: DataHubPOSTButton, userPathButton
        function Config_FolderButtonPushed(app, event)
            
            try
                relatedFolder = eval(sprintf('app.config_Folder_%s.Value', event.Source.Tag));                    
            catch
                relatedFolder = app.mainApp.General.fileFolder.(event.Source.Tag);
            end
            
            if isfolder(relatedFolder)
                initialFolder = relatedFolder;
            elseif isfile(relatedFolder)
                initialFolder = fileparts(relatedFolder);
            else
                initialFolder = app.userPath.Value;
            end
            
            selectedFolder = uigetdir(initialFolder);
            figure(app.UIFigure)

            if selectedFolder
                switch event.Source
                    case app.DataHubPOSTButton
                        if strcmp(app.mainApp.General.fileFolder.DataHub_POST, selectedFolder) 
                            return
                        else
                            selectedFolderFiles = dir(selectedFolder);
                            if ~ismember('.monitorsped_post', {selectedFolderFiles.name})
                                appUtil.modalWindow(app.UIFigure, 'error', 'Não se trata da pasta "DataHub - POST", do monitorSPED.');
                                return
                            end

                            app.DataHubPOST.Value = selectedFolder;
                            app.mainApp.General.fileFolder.DataHub_POST = selectedFolder;
    
                            ipcMainMatlabCallsHandler(app.mainApp, app, 'checkDataHubLampStatus')
                        end

                    case app.userPathButton
                        app.userPath.Value = selectedFolder;
                        app.mainApp.General.fileFolder.userPath = selectedFolder;
                end

                app.mainApp.General_I.fileFolder = app.mainApp.General.fileFolder;
                saveGeneralSettings(app)
                Folder_updatePanel(app)
            end

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'monitorSPED';
            app.UIFigure.Icon = 'icon_16.png';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @closeFcn, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.TabGroup = uitabgroup(app.GridLayout);
            app.TabGroup.SelectionChangedFcn = createCallbackFcn(app, @TabGroup_TabSelectionChanged, true);
            app.ASPECTOSGERAISTab = uitab(app.TabGroup);
            app.ASPECTOSGERAISTab.Title = 'ASPECTOS GERAIS';
            app.General_Grid = uigridlayout(app.ASPECTOSGERAISTab);
            app.versionInfo = uilabel(app.General_Grid);
            app.versionInfo.Interpreter = 'html';
            app.versionInfo.Text = '';
            app.openAuxiliarAppAsDocked = uicheckbox(app.General_Grid);
            app.openAuxiliarAppAsDocked.ValueChangedFcn = createCallbackFcn(app, @Config_ParameterValueChanged, true);
            app.openAuxiliarAppAsDocked.Enable = 'off';
            app.openAuxiliarAppAsDocked.Text = 'Modo DOCK: módulos auxiliares abertos na janela principal do app';
            app.openAuxiliarApp2Debug = uicheckbox(app.General_Grid);
            app.openAuxiliarApp2Debug.ValueChangedFcn = createCallbackFcn(app, @Config_ParameterValueChanged, true);
            app.openAuxiliarApp2Debug.Enable = 'off';
            app.openAuxiliarApp2Debug.Text = 'Modo DEBUG';
            app.AMBIENTELabel = uilabel(app.General_Grid);
            app.AMBIENTELabel.Text = 'AMBIENTE:';
            app.versionInfoRefresh = uiimage(app.General_Grid);
            app.versionInfoRefresh.ImageClickedFcn = createCallbackFcn(app, @Toolbar_AppEnvRefreshButtonPushed, true);
            app.versionInfoRefresh.Enable = 'off';
            app.versionInfoRefresh.Tooltip = {'Verifica atualizações'};
            app.versionInfoRefresh.ImageSource = 'Refresh_18.png';
            app.ANLISETab = uitab(app.TabGroup);
            app.ANLISETab.Title = 'ANÁLISE';
            app.GridLayout_3 = uigridlayout(app.ANLISETab);
            app.PROCESSODELEITURADOSARQUIVOSEVISUALIZAODOSSEUSMETADADOSLabel = uilabel(app.GridLayout_3);
            app.PROCESSODELEITURADOSARQUIVOSEVISUALIZAODOSSEUSMETADADOSLabel.Text = 'PROCESSO DE LEITURA DOS ARQUIVOS E VISUALIZAÇÃO DOS SEUS METADADOS';
            app.Panel = uipanel(app.GridLayout_3);
            app.GridLayout3 = uigridlayout(app.Panel);
            app.InputTypeLabel = uilabel(app.GridLayout3);
            app.InputTypeLabel.Text = 'ENTRADA:';
            app.InputType = uidropdown(app.GridLayout3);
            app.InputType.Items = {'file', 'folder'};
            app.InputType.ValueChangedFcn = createCallbackFcn(app, @Config_ParameterValueChanged, true);
            app.InputType.Value = 'file';
            app.SortMethodLabel = uilabel(app.GridLayout3);
            app.SortMethodLabel.Text = 'VISUALIZAÇÃO ÁRVORE:';
            app.SortMethod = uidropdown(app.GridLayout3);
            app.SortMethod.Items = {'CNPJ', 'PERÍODO FISCAL', 'RECEITA FEDERAL'};
            app.SortMethod.ValueChangedFcn = createCallbackFcn(app, @Config_ParameterValueChanged, true);
            app.SortMethod.Value = 'CNPJ';
            app.CheckStatusLabel = uilabel(app.GridLayout3);
            app.CheckStatusLabel.Text = 'CONSULTA RECEITA FEDERAL:';
            app.CheckStatus = uidropdown(app.GridLayout3);
            app.CheckStatus.Items = {'OnlyCache', 'Cache+RealTime', 'RealTime'};
            app.CheckStatus.ValueChangedFcn = createCallbackFcn(app, @Config_ParameterValueChanged, true);
            app.CheckStatus.Value = 'Cache+RealTime';
            app.EncodingLabel = uilabel(app.GridLayout3);
            app.EncodingLabel.Text = 'CODIFICAÇÃO CARACTERE:';
            app.Encoding = uidropdown(app.GridLayout3);
            app.Encoding.Items = {};
            app.Encoding.ValueChangedFcn = createCallbackFcn(app, @Config_ParameterValueChanged, true);
            app.Encoding.Enable = 'off';
            app.Encoding.Value = {};
            app.PROCESSODEANLISEDOSDADOSLabel = uilabel(app.GridLayout_3);
            app.PROCESSODEANLISEDOSDADOSLabel.Text = 'PROCESSO DE ANÁLISE DOS DADOS';
            app.Panel_2 = uipanel(app.GridLayout_3);
            app.GridLayout4 = uigridlayout(app.Panel_2);
            app.fontstylefontsize32pxfontTRABALHOEMANDAMENTOLabel = uilabel(app.GridLayout4);
            app.fontstylefontsize32pxfontTRABALHOEMANDAMENTOLabel.Interpreter = 'html';
            app.fontstylefontsize32pxfontTRABALHOEMANDAMENTOLabel.Text = {'<font style="font-size: 32px;">🚧</font>'; ''; 'TRABALHO EM '; 'ANDAMENTO'};
            app.MAPEAMENTODEPASTASTab = uitab(app.TabGroup);
            app.MAPEAMENTODEPASTASTab.Title = 'MAPEAMENTO DE PASTAS';
            app.FolderMapGrid = uigridlayout(app.MAPEAMENTODEPASTASTab);
            app.DATAHUBPOSTLabel = uilabel(app.FolderMapGrid);
            app.DATAHUBPOSTLabel.Text = 'DATAHUB - POST:';
            app.DataHubPOST = uieditfield(app.FolderMapGrid, 'text');
            app.DataHubPOST.Editable = 'off';
            app.DataHubPOSTButton = uiimage(app.FolderMapGrid);
            app.DataHubPOSTButton.ImageClickedFcn = createCallbackFcn(app, @Config_FolderButtonPushed, true);
            app.DataHubPOSTButton.Tag = 'DataHub_POST';
            app.DataHubPOSTButton.Enable = 'off';
            app.DataHubPOSTButton.ImageSource = 'OpenFile_36x36.png';
            app.userPathLabel = uilabel(app.FolderMapGrid);
            app.userPathLabel.Text = 'PASTA DO USUÁRIO:';
            app.userPath = uieditfield(app.FolderMapGrid, 'text');
            app.userPath.Editable = 'off';
            app.userPathButton = uiimage(app.FolderMapGrid);
            app.userPathButton.ImageClickedFcn = createCallbackFcn(app, @Config_FolderButtonPushed, true);
            app.userPathButton.Tag = 'userPath';
            app.userPathButton.Enable = 'off';
            app.userPathButton.ImageSource = 'OpenFile_36x36.png';
            app.GridLayout2 = uigridlayout(app.GridLayout);
            app.openDevTools = uiimage(app.GridLayout2);
            app.openDevTools.ImageClickedFcn = createCallbackFcn(app, @Toolbar_OpenDevToolsClicked, true);
            app.openDevTools.Enable = 'off';
            app.openDevTools.Tooltip = {'Abre DevTools'};
            app.openDevTools.ImageSource = 'Debug_18.png';
            app.tool_simulationMode = uiimage(app.GridLayout2);
            app.tool_simulationMode.ImageClickedFcn = createCallbackFcn(app, @Toolbar_SimulationModeButtonPushed, true);
            app.tool_simulationMode.Tooltip = {'Leitura arquivos de simulação'};
            app.tool_simulationMode.ImageSource = 'Import_16.png';
            app.dockModuleGrid = uigridlayout(app.GridLayout);
            app.dockModuleGrid.Visible = 'off';
            app.dockModule_Close = uiimage(app.dockModuleGrid);
            app.dockModule_Close.ImageClickedFcn = createCallbackFcn(app, @DockModuleGroup_ButtonPushed, true);
            app.dockModule_Close.Tag = 'DRIVETEST';
            app.dockModule_Close.Tooltip = {'Fecha módulo'};
            app.dockModule_Close.ImageSource = 'Delete_12SVG_white.svg';
            app.dockModule_Undock = uiimage(app.dockModuleGrid);
            app.dockModule_Undock.ImageClickedFcn = createCallbackFcn(app, @DockModuleGroup_ButtonPushed, true);
            app.dockModule_Undock.Tag = 'DRIVETEST';
            app.dockModule_Undock.Enable = 'off';
            app.dockModule_Undock.Tooltip = {'Reabre módulo em outra janela'};
            app.dockModule_Undock.ImageSource = 'Undock_18White.png';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = winConfig(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end