classdef winECD < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure              matlab.ui.Figure
        GridLayout            matlab.ui.container.GridLayout
        dockModuleGrid        matlab.ui.container.GridLayout
        dockModule_Undock     matlab.ui.control.Image
        dockModule_Close      matlab.ui.control.Image
        TabGroup              matlab.ui.container.TabGroup
        Tab1                  matlab.ui.container.Tab
        Tab1Grid              matlab.ui.container.GridLayout
        MiscellaneousLabel    matlab.ui.control.Label
        ExportExcel           matlab.ui.control.Button
        OpenRTFFiles          matlab.ui.control.Image
        ReadProcessLog        matlab.ui.control.Image
        Tab1Separator         matlab.ui.control.Image
        SheetList             matlab.ui.control.DropDown
        SheetListLabel        matlab.ui.control.Label
        TimePeriodList        matlab.ui.control.DropDown
        TimePeriodListLabel   matlab.ui.control.Label
        CompanyNameList       matlab.ui.control.DropDown
        CompanyNameListLabel  matlab.ui.control.Label
        Tab2                  matlab.ui.container.Tab
        Tab2Grid              matlab.ui.container.GridLayout
        StyleRefresh          matlab.ui.control.Image
        StyleDelete           matlab.ui.control.Image
        FontIconLabel         matlab.ui.control.Label
        FontIcon              matlab.ui.control.DropDown
        Tab2Separator3        matlab.ui.control.Image
        FontColor             matlab.ui.control.ColorPicker
        FontBackground        matlab.ui.control.ColorPicker
        FontAlign3            matlab.ui.control.Image
        FontAlign2            matlab.ui.control.Image
        FontAlign1            matlab.ui.control.Image
        FontStyle             matlab.ui.control.Button
        FontWeight            matlab.ui.control.Button
        FontFamily            matlab.ui.control.DropDown
        Tab2Separator2        matlab.ui.control.Image
        ColumnWidthLabel      matlab.ui.control.Label
        ColumnWidth           matlab.ui.control.DropDown
        RowHeightLabel        matlab.ui.control.Label
        RowHeight             matlab.ui.control.Spinner
        Tab2Separator1        matlab.ui.control.Image
        SheetOnFocus          matlab.ui.control.Lamp
        SheetHeight_Second    matlab.ui.control.Spinner
        SheetView_Second      matlab.ui.control.DropDown
        SheetHeight_First     matlab.ui.control.Spinner
        SheetView_First       matlab.ui.control.DropDown
        SheetViewStatus       matlab.ui.control.StateButton
        UITable2_AccountInfo  matlab.ui.control.Label
        UITable2_FilterText   matlab.ui.control.Label
        UITable2_CountText    matlab.ui.control.Label
        UITable2_CountIcon    matlab.ui.control.Image
        UITable2              matlab.ui.control.Table
        UITable1_AccountInfo  matlab.ui.control.Label
        UITable1_FilterText   matlab.ui.control.Label
        UITable1_CountText    matlab.ui.control.Label
        UITable1_CountIcon    matlab.ui.control.Image
        UITable1              matlab.ui.control.Table
        toolGrid              matlab.ui.container.GridLayout
        tool_UploadFinalFile  matlab.ui.control.Image
        tool_GenerateReport   matlab.ui.control.Image
        tool_CompanyInfo      matlab.ui.control.Label
    end

    
    properties (Access = public)
        %-----------------------------------------------------------------%
        Container
        isDocked = false

        mainApp

        % A função do timer é executada uma única vez após a renderização
        % da figura, lendo arquivos de configuração, iniciando modo de operação
        % paralelo etc. A ideia é deixar o MATLAB focar apenas na criação dos 
        % componentes essenciais da GUI (especificados em "createComponents"), 
        % mostrando a GUI para o usuário o mais rápido possível.
        timerObj
        jsBackDoor

        % Janela de progresso já criada no DOM. Dessa forma, controla-se 
        % apenas a sua visibilidade - e tornando desnecessário criá-la a
        % cada chamada (usando uiprogressdlg, por exemplo).
        progressDialog
        popupContainer

        %-----------------------------------------------------------------%
        % ESPECIFICIDADES AUXAPP.ECD
        %-----------------------------------------------------------------%
        projectData
        ecdObj
    end


    methods
        %-----------------------------------------------------------------%
        function ipcSecundaryJSEventsHandler(app, event)
            try
                switch event.HTMLEventName
                    case 'renderer'
                        startup_Controller(app)

                    case 'getCssPropertyValue'
                        if ~isempty(event.HTMLEventData.componentName)
                            if ~isprop(app, 'isDocked') % mainApp (app container)
                                auxAppTag = event.HTMLEventData.auxAppTag;
                                if ~isempty(auxAppTag)
                                    hAuxApp   = auxAppHandle(app, auxAppTag);
                                    objHandle = hAuxApp.(componentName);
                                else
                                    objHandle = eval(['app.' event.HTMLEventData.componentName]);
                                end
                            else
                                objHandle = eval(['app.' event.HTMLEventData.componentName]);
                            end
                            
                            cssProp  = event.HTMLEventData.propertyName;
                            cssValue = event.HTMLEventData.propertyValue;
    
                            if ~isprop(objHandle, 'StyleObservations')
                                objHandle.addprop('StyleObservations');
                            end
                            objHandle.StyleObservations.(cssProp) = cssValue;
                        end

                    otherwise
                        error('UnexpectedEvent')
                end

            catch ME
                appUtil.modalWindow(app.UIFigure, 'error', ME.message);
            end
        end

        %-----------------------------------------------------------------%
        function varargout = ipcSecundaryMatlabCallsHandler(app, callingApp, operationType, varargin)
            % ...
        end
    end

    
    methods (Access = private)
        %-----------------------------------------------------------------%
        % INICIALIZAÇÃO
        %-----------------------------------------------------------------%
        function jsBackDoor_Initialization(app)
            app.jsBackDoor = uihtml(app.UIFigure, "HTMLSource",           appUtil.jsBackDoorHTMLSource(),                 ...
                                                  "HTMLEventReceivedFcn", @(~, evt)ipcSecundaryJSEventsHandler(app, evt), ...
                                                  "Visible",              "off");
        end

        %-------------------------------------------------------------------------%
        function jsBackDoor_Customizations(app, tabIndex)
            persistent customizationStatus
            if isempty(customizationStatus)
                customizationStatus = [false, false, false, false];
            end

            switch tabIndex
                case 0 % STARTUP
                    if app.isDocked
                        app.progressDialog = app.mainApp.progressDialog;
                    else
                        sendEventToHTMLSource(app.jsBackDoor, 'startup', app.mainApp.executionMode);
                        app.progressDialog = ccTools.ProgressDialog(app.jsBackDoor);                        
                    end
                    customizationStatus = [false, false, false, false];

                otherwise
                    if customizationStatus(tabIndex)
                        return
                    end

                    customizationStatus(tabIndex) = true;
                    switch tabIndex
                        case 1
                            % Grid botões "dock":
                            if app.isDocked
                                elToModify = {app.dockModuleGrid};
                                elDataTag  = ui.CustomizationBase.getElementsDataTag(elToModify);
                                if ~isempty(elDataTag)
                                    appName = class(app);                    
                                    sendEventToHTMLSource(app.jsBackDoor, 'initializeComponents', { ...
                                        struct('appName', appName, 'dataTag', elDataTag{1}, 'style', struct('transition', 'opacity 2s ease', 'opacity', '0.5')) ...
                                    });
                                end
                            end

                            % Outros elementos:
                            hTableList = {app.UITable1, app.UITable2};
                            ui.CustomizationBase.getElementsDataTag(hTableList);

                        case 2
                            app.FontFamily.Items = [{''}; listfonts];

                        otherwise
                            % Customização de componentes constantes nas outras abas, 
                            % os quais são renderizados completamente apenas após a 
                            % abertura da aba.
                            % ...
                    end
            end
        end

        %-----------------------------------------------------------------%
        function startup_timerCreation(app)
            app.timerObj = timer("ExecutionMode", "fixedSpacing", ...
                                 "StartDelay",    1.5,            ...
                                 "Period",        .1,             ...
                                 "TimerFcn",      @(~,~)app.startup_timerFcn);
            start(app.timerObj)
        end

        %-----------------------------------------------------------------%
        function startup_timerFcn(app)
            if ccTools.fcn.UIFigureRenderStatus(app.UIFigure)
                stop(app.timerObj)
                delete(app.timerObj)
                
                jsBackDoor_Initialization(app)
            end
        end

        %-----------------------------------------------------------------%
        function startup_Controller(app)
            drawnow
            jsBackDoor_Customizations(app, 0)
            jsBackDoor_Customizations(app, 1)

            app.progressDialog.Visible = 'visible';
            
            startup_AppProperties(app)
            startup_GUIComponents(app)

            app.progressDialog.Visible = 'hidden';
        end

        %-----------------------------------------------------------------%
        function startup_AppProperties(app)            
            % ...
        end

        %-----------------------------------------------------------------%
        function startup_GUIComponents(app)
            if ~strcmp(app.mainApp.executionMode, 'webApp')
                app.dockModule_Undock.Enable = 1;
            end

            % Tabelas:
            app.UITable1.RowName = 'numbered';
            app.UITable2.RowName = 'numbered';
            restartTableSelectionControl(app, app.UITable1, app.UITable1_CountText)
            restartTableSelectionControl(app, app.UITable2, app.UITable2_CountText)

            % Seleção inicial:
            if ~isempty(app.ecdObj)
                idsList = {app.ecdObj.CompanyId};
                [ids, ~, idsIndexes] = unique(idsList);

                idsNames = {};
                mappingIds = dictionary();
                
                for ii = 1:numel(ids)
                    idIndexes = find(ii == idsIndexes);
                    [~, idSortedIndexes] = sort(arrayfun(@(x) x.Period(2), app.ecdObj(idIndexes)));

                    % Nome empresa que aparecerá no dropdown (idêntico à
                    % forma da uitree, no winMonitorSPED.mlapp)
                    idsNames{end+1} = util.HtmlTextGenerator.generateTextId(app.ecdObj(idIndexes(1)), 'company-oriented');
                    mappingIds = mappingIds.insert(string(ids{ii}), {idIndexes(idSortedIndexes)});
                end

                % Empresas:
                app.CompanyNameList.Items = idsNames;
                app.CompanyNameList.UserData = mappingIds;

                % A seleção buscará respeitar aquilo que estiver selecionado 
                % em winMonitorSPED.mlapp.
                fileIndex = 1;
                if ~isempty(app.mainApp.file_Tree.SelectedNodes)
                    fileIndex = unique([app.mainApp.file_Tree.SelectedNodes.NodeData], 'stable');
                    fileIndex = fileIndex(1);
                end
                selectedCompanyIndex = find(cellfun(@(x) ismember(fileIndex, x), app.CompanyNameList.UserData.values), 1);
                app.CompanyNameList.Value = app.CompanyNameList.Items{selectedCompanyIndex};
                
                updateTimePeriodList(app, fileIndex)
                TimePeriodListValueChanged(app)
            end
        end

        %-----------------------------------------------------------------%
        function menu_LayoutPopupApp(app, auxiliarApp, varargin)
            arguments
                app
                auxiliarApp char {mustBeMember(auxiliarApp, {'ReportLib'})}
            end

            arguments (Repeating)
                varargin 
            end

            % Inicialmente ajusta as dimensões do container.
            switch auxiliarApp
                case 'ReportLib'
                    screenWidth  = 460;
                    screenHeight = 308;
            end

            ui.PopUpContainer(app, class.Constants.appName, screenWidth, screenHeight)

            % Executa o app auxiliar.
            inputArguments = [{app}, varargin];
            
            if app.mainApp.General.operationMode.Debug
                eval(sprintf('auxApp.dock%s(inputArguments{:})', auxiliarApp))
            else
                eval(sprintf('auxApp.dock%s_exported(app.popupContainer, inputArguments{:})', auxiliarApp))
                app.popupContainer.Parent.Visible = 1;
            end            
        end
    end


    methods (Access = private)
        %-----------------------------------------------------------------%
        function companyIndexes = selectedFileIndexByCompany(app)
            companyId      = extractBefore(app.CompanyNameList.Value, ' -');
            companyIndexes = cell2mat(app.CompanyNameList.UserData(companyId));
        end

        %-----------------------------------------------------------------%
        function [selectedECD, fileIndex] = selectedECDObject(app)
            companyIndexes  = selectedFileIndexByCompany(app);
             [~, companySortedIndexes] = sort(arrayfun(@(x) x.Period(2), app.ecdObj(companyIndexes)));
             companyIndexes = companyIndexes(companySortedIndexes);

            if isnumeric(app.TimePeriodList.Value)
                fileIndex = companyIndexes(app.TimePeriodList.Value);
            else
                fileIndex = companyIndexes(strcmp(app.TimePeriodList.Items, app.TimePeriodList.Value));
            end

            if ~isscalar(fileIndex)
                fileIndex = fileIndex(1);
            end

            selectedECD   = app.ecdObj(fileIndex);
        end

        %-----------------------------------------------------------------%
        function updateTimePeriodList(app, fileIndex)
            companyIndexes = selectedFileIndexByCompany(app);
            [~, companySortedIndexes] = sort(arrayfun(@(x) x.Period(2), app.ecdObj(companyIndexes)));
            
            companyIndexes = companyIndexes(companySortedIndexes);
            if isempty(fileIndex)
                selectedPeriodIndex = 1;
            else
                [~, selectedPeriodIndex] = ismember(fileIndex, companyIndexes);
            end

            periodList = {};
            for ii = 1:numel(companyIndexes)
                idx = companyIndexes(ii);
                periodList{end+1} = util.HtmlTextGenerator.generateTextId(app.ecdObj(idx), 'period-oriented', true);
            end

            set(app.TimePeriodList, 'Items', periodList, 'ItemsData', 1:numel(periodList))
            app.TimePeriodList.Value = selectedPeriodIndex;
        end

        %-----------------------------------------------------------------%
        function updateSheetList(app)
            selectedECD = selectedECDObject(app);

            [ordinaryIds, customIds] = getTableIds(selectedECD);
            sheetsSorted = [ordinaryIds; customIds];

            app.SheetList.Items        = sheetsSorted;
            app.SheetView_First.Items  = sheetsSorted;
            app.SheetView_Second.Items = sheetsSorted;
        end

        %-----------------------------------------------------------------%
        function updateTable(app, hTable, hTableAccountInfo, hTableCountText, hTableFilterText, tableId)
            [selectedECD, fileIndex] = selectedECDObject(app);

            if ~startsWith(tableId, 'm')
                tableIdField = ['x' tableId];
                if isTableRead(selectedECD, {tableId})
                    ipcMainMatlabCallsHandler(app.mainApp, app, 'updateTreeView', fileIndex);
                end
            else
                tableIdField = tableId;
            end            
            tableIdData = selectedECD.Table.(tableIdField);
            
            columnName  = tableIdData.Properties.VariableNames;
            columnEditable = contains(columnName, '✎');
            
            set(hTable, 'ColumnWidth', 'auto', ...
                        'ColumnName', columnName, ...
                        'ColumnEditable', columnEditable, ...
                        'Data', tableIdData)
            hTable.UserData.tableId = tableId;

            restartTableSelectionControl(app, hTable, hTableAccountInfo, hTableCountText)
            applyTableStyle(app, selectedECD, hTable, tableId)

            if height(hTable.Data) > 1
                numberOfRowsText = sprintf('%d DE %d LINHAS ', height(hTable.Data), height(hTable.Data)); % PENDENTE FILTRAGEM
            else
                numberOfRowsText = sprintf('%d DE %d LINHA ',  height(hTable.Data), height(hTable.Data)); % PENDENTE FILTRAGEM
            end
            hTableFilterText.Text = numberOfRowsText;
        end

        %-----------------------------------------------------------------%
        function restartTableSelectionControl(app, hTable, hTableAccountInfo, hTableCountText)
            hTable.Selection = [];
            
            % ().UserData.id armazenará o "data-tag" do componente, caso haja
            % alguma customização em curso.
            hTable.UserData.Selection = [];
            hTable.UserData.SelectionType = 'none';

            hTableAccountInfo.Text = '';
            hTableCountText.Text   = '  CONTAGEM: 0';
        end

        %-----------------------------------------------------------------%
        function applyTableStyle(app, selectedECD, hTable, tableId)
            if ~isempty(hTable.StyleConfigurations)
                removeStyle(hTable)
            end
            
            tableIdIndex = find(strcmp({selectedECD.GUI.tableView.id}, tableId), 1);
            if ~isempty(tableIdIndex)
                styleConfigTable = selectedECD.GUI.tableView(tableIdIndex).style;
                for ii = 1:height(styleConfigTable)
                    addStyle(hTable, styleConfigTable.Style(ii), styleConfigTable.Target(ii), styleConfigTable.TargetIndex{ii})
                end
            end
        end

        %-----------------------------------------------------------------%
        function [hTable, hTableName] = onFocusTable(app)
            if app.SheetOnFocus.Layout.Row == 1
                hTable     = app.UITable1;
                hTableName = 'app.UITable1';
            else
                hTable     = app.UITable2;
                hTableName = 'app.UITable2';
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainApp, filterTable, rfDataHubAnnotation)
            
            app.mainApp     = mainApp;
            app.projectData = mainApp.projectData;
            app.ecdObj      = mainApp.ecdObj;

            if app.isDocked
                app.GridLayout.Padding(4)  = 30;
                app.dockModuleGrid.Visible = 1;
                app.jsBackDoor = mainApp.jsBackDoor;
                startup_Controller(app)
            else
                appUtil.winPosition(app.UIFigure)
                startup_timerCreation(app)
            end

        end

        % Close request function: UIFigure
        function closeFcn(app, event)

            ipcMainMatlabCallsHandler(app.mainApp, app, 'closeFcn')
            delete(app)
            
        end

        % Image clicked function: dockModule_Close, dockModule_Undock
        function DockModuleGroup_ButtonPushed(app, event)
            
            [idx, auxAppTag, relatedButton] = getAppInfoFromHandle(app.mainApp.tabGroupController, app);

            switch event.Source
                case app.dockModule_Undock
                    appGeneral = app.mainApp.General;
                    appGeneral.operationMode.Dock = false;

                    inputArguments = ipcMainMatlabCallsHandler(app.mainApp, app, 'dockButtonPushed', auxAppTag);
                    app.mainApp.tabGroupController.Components.appHandle{idx} = [];
                    
                    openModule(app.mainApp.tabGroupController, relatedButton, false, appGeneral, inputArguments{:})
                    closeModule(app.mainApp.tabGroupController, auxAppTag, app.mainApp.General, 'undock')
                    
                    delete(app)

                case app.dockModule_Close
                    closeModule(app.mainApp.tabGroupController, auxAppTag, app.mainApp.General)
            end

        end

        % Image clicked function: ReadProcessLog
        function Toolbar_LOGInfoImageClicked(app, event)
            
            selectedECD = selectedECDObject(app);

            htmlContent = util.HtmlTextGenerator.Warnings(selectedECD);
            appUtil.modalWindow(app.UIFigure, 'info', htmlContent);

        end

        % Image clicked function: tool_GenerateReport
        function Toolbar_ReportImageClicked(app, event)
            
        end

        % Button pushed function: ExportExcel
        function Toolbar_ExportImageClicked(app, event)
            
            selectedECD = selectedECDObject(app);

            nameFormatMap = {'*.xlsx', 'Excel (*.xlsx)'};
            defaultName   = appUtil.DefaultFileName(app.mainApp.General.fileFolder.userPath, 'monitorSPED', -1);
            [fileFullPath, ~, ~, fileName] = appUtil.modalWindow(app.UIFigure, 'uiputfile', '', nameFormatMap, defaultName);
            if isempty(fileFullPath)
                return
            end

            app.progressDialog.Visible = 'visible';

            try
                tempName = fullfile(app.mainApp.General.fileFolder.userPath, [fileName '.xlsx']);
                tableIds = setdiff(fieldnames(selectedECD.Table), {'x0000'});
                
                writetable(selectedECD.Table.x0000, tempName, "Sheet", "0000", "WriteMode", "replacefile")
                for ii = 1:numel(tableIds)
                    tableId = tableIds{ii};                    
                    writetable(selectedECD.Table.(tableId), tempName, "Sheet", tableId(2:end), "WriteMode", "append")
                end

                copyfile(tempName, fileFullPath, 'f')

                if ~strcmp(app.mainApp.executionMode, 'webApp')
                    ccTools.fcn.OperationSystem('openFile', fileFullPath)
                end

            catch ME
                appUtil.modalWindow(app.UIFigure, 'warning', getReport(ME));
            end

            app.progressDialog.Visible = 'hidden';

        end

        % Image clicked function: OpenRTFFiles
        function Toolbar_OpenRTFImageClicked(app, event)
            
            selectedECD = selectedECDObject(app);

            rtfFiles    = {};
            msgError    = {};

            rtfTableIds = {'J800', 'J801'};
            parseTableAndAddToCache(selectedECD, rtfTableIds)

            defaultName = appUtil.DefaultFileName(app.mainApp.General.fileFolder.userPath, 'monitorSPED');
            tempName    = appUtil.DefaultFileName(app.mainApp.General.fileFolder.tempPath, 'monitorSPED');
            fileCount   = 0;

            app.progressDialog.Visible = 'visible';
            
            for ii = 1:numel(rtfTableIds)
                rtfTableField = ['x' rtfTableIds{ii}];
        
                if isfield(selectedECD.Table, rtfTableField) && ~isempty(selectedECD.Table.(rtfTableField))
                    for jj = 1:height(selectedECD.Table.(rtfTableField))
                        fileCount = fileCount+1;
                        fileName  = sprintf('%s_%d.rtf', tempName, fileCount);
                        
                        try
                            util.recreateRTF(selectedECD.Table.(rtfTableField).('ARQ_RTF'){jj}, fileName)
                            rtfFiles{end+1} = fileName;

                        catch ME
                            msgError{end+1} = ME.message;
                        end
                    end
                end
            end

            app.progressDialog.Visible = 'hidden';

            if ~isempty(rtfFiles)
                appName = class.Constants.appName;

                if isscalar(rtfFiles) && ~strcmp(app.mainApp.executionMode, 'webApp')
                    nameFormat = {'*.rtf', [appName, ' (*.rtf)']};
                else
                    nameFormat = {'*.zip', [appName, ' (*.zip)']};
                end

                outputFile = appUtil.modalWindow(app.UIFigure, 'uiputfile', '', nameFormat, defaultName);
                if isempty(outputFile)
                    return
                end

                app.progressDialog.Visible = 'visible';

                if isscalar(rtfFiles) && ~strcmp(app.mainApp.executionMode, 'webApp')
                    copyfile(rtfFiles{1}, outputFile, 'f')
                else
                    zip(outputFile, rtfFiles)
                end

                if ~strcmp(app.mainApp.executionMode, 'webApp')
                    for kk = 1:numel(rtfFiles)
                        ccTools.fcn.OperationSystem('openFile', rtfFiles{kk})
                    end
                end

                app.progressDialog.Visible = 'hidden';
            else
                appUtil.modalWindow(app.UIFigure, 'info', sprintf('Escrituração não possui arquivo auxiliar no formato RTF.'));
            end
        
            if ~isempty(msgError)
                msgError = strjoin(msgError, '\n');
                appUtil.modalWindow(app.UIFigure, 'error', msgError);
            end

        end

        % Selection change function: TabGroup
        function TabGroupSelectionChanged(app, event)
            
            [~, tabIndex] = ismember(app.TabGroup.SelectedTab, app.TabGroup.Children);
            jsBackDoor_Customizations(app, tabIndex)

        end

        % Value changed function: CompanyNameList
        function CompanyNameListValueChanged(app, event)

            updateTimePeriodList(app, [])
            TimePeriodListValueChanged(app)

        end

        % Value changed function: TimePeriodList
        function TimePeriodListValueChanged(app, event)
            
            selectedECD = selectedECDObject(app);

            app.tool_CompanyInfo.Text = sprintf('<font style="font-size: 11px; font-weight: bold;">%s</font> CNPJ %s (%s) \n%s ', ...
                upper(selectedECD.CompanyName), selectedECD.CompanyId, selectedECD.State, strjoin(string(selectedECD.Period), ' a '));

            updateSheetList(app)
            SheetViewFirstValueChanged(app, struct('Source', app.SheetList))
            SheetViewSecondValueChanged(app)
            
        end

        % Value changed function: SheetList, SheetView_First
        function SheetViewFirstValueChanged(app, event)
            
            switch event.Source
                case app.SheetList
                    app.SheetView_First.Value = app.SheetList.Value;
                case app.SheetView_First
                    app.SheetList.Value = app.SheetView_First.Value;
            end

            updateTable(app, app.UITable1, app.UITable1_AccountInfo, app.UITable1_CountText, app.UITable1_FilterText, app.SheetList.Value)

        end

        % Value changed function: SheetView_Second
        function SheetViewSecondValueChanged(app, event)
            
            updateTable(app, app.UITable2, app.UITable2_AccountInfo, app.UITable2_CountText, app.UITable2_FilterText, app.SheetView_Second.Value)
            
        end

        % Value changed function: SheetViewStatus
        function SheetViewStatusValueChanged(app, event)
            
            if app.SheetViewStatus.Value
                app.SheetView_Second.Enable  = "on";
                app.SheetHeight_First.Enable = 'on';

                app.SheetHeight_Second.Limits(1) = 1;
                set(app.SheetHeight_Second, 'Enable', 'on', 'Value', app.SheetHeight_First.Value)
                
                app.UITable2.Visible = 'on';
                rowHeight = {10,2,22};
                
            else
                app.SheetView_Second.Enable  = "off";
                app.SheetHeight_First.Enable = 'off';
                
                app.SheetHeight_Second.Limits(1) = 0;
                set(app.SheetHeight_Second, 'Enable', 'off', 'Value', 0)

                app.UITable2.Visible = 'off';
                rowHeight = {0,0,0};

                if app.SheetOnFocus.Layout.Row ~= 1
                    app.SheetOnFocus.Layout.Row = 1;
                end
            end

            SheetViewHeightValueChanged(app, struct('Source', app.SheetHeight_First))
            app.GridLayout.RowHeight([9,11,12]) = rowHeight;
            
        end

        % Value changed function: SheetHeight_First, SheetHeight_Second
        function SheetViewHeightValueChanged(app, event)
            
            app.GridLayout.RowHeight([6,10]) = {sprintf('%dx', app.SheetHeight_First.Value), sprintf('%dx', app.SheetHeight_Second.Value)};

        end

        % Callback function: UITable1, UITable1, UITable2, UITable2
        function TableClicked(app, event)
            
            clickedTable = event.Source;

            switch event.EventName
                case 'Clicked'
                    clickedRow = event.InteractionInformation.DisplayRow;
                    clickedCol = event.InteractionInformation.DisplayColumn;
                case 'KeyRelease'
                    if isempty(clickedTable.Selection)
                        clickedRow = [];
                        clickedCol = [];
                    else
                        clickedRow = clickedTable.Selection(:,1);
                        clickedCol = clickedTable.Selection(:,2);
                    end
            end

            switch clickedTable
                case app.UITable1
                    tableCountText       = app.UITable1_CountText;
                    tableSelectedAccount = app.UITable1_AccountInfo;
                    lampPositionRow      = 1;
                case app.UITable2
                    tableCountText       = app.UITable2_CountText;
                    tableSelectedAccount = app.UITable2_AccountInfo;
                    lampPositionRow      = 2;
            end

            % Altera tabela em evidência (uilamp), além de definir o tipo
            % de seleção (no caso de clique fora da região de células,
            % limpa-se a seleção prévia).
            if app.SheetOnFocus.Layout.Row ~= lampPositionRow
                app.SheetOnFocus.Layout.Row = lampPositionRow;
            end

            % Altera o tipo de seleção.
            if isempty(clickedRow) && isempty(clickedCol) 
                if ~isempty(clickedTable.Selection)
                    clickedTable.UserData.SelectionType = 'none';                    
                    clickedTable.Selection = [];
                    drawnow                    
                end
            elseif isempty(clickedCol)
                clickedTable.UserData.SelectionType = 'row';
            elseif isempty(clickedRow)
                clickedTable.UserData.SelectionType = 'column';
            else
                clickedTable.UserData.SelectionType = 'cell';            
            end

            % Altera informações no rodapé da tabela.
            if ~isequal(clickedTable.Selection, clickedTable.UserData.Selection)
                clickedTable.UserData.Selection = clickedTable.Selection;

                if isempty(clickedTable.Selection)
                    tableCountText.Text       = '  CONTAGEM: 0';
                    tableSelectedAccount.Text = '';                    
                else
                    selectedCols = unique(clickedTable.Selection(:, 2));
                    selectedColsNames = clickedTable.Data.Properties.VariableNames(selectedCols);
    
                    isNumeric = true;
                    for ii = 1:numel(selectedColsNames)
                        if ~isnumeric(clickedTable.Data.(selectedColsNames{ii}))
                            isNumeric = false;
                            break;
                        end
                    end
    
                    cellsCount = height(clickedTable.Selection);
                    if isNumeric
                        switch clickedTable.UserData.SelectionType
                            case 'column'
                                cellsSum = sum(double(clickedTable.Data{:, selectedCols}), 'all');
                                cellsAverage = mean(double(clickedTable.Data{:, selectedCols}), 'all');
    
                            otherwise
                                cellsSum = 0;
                                for kk = 1:cellsCount
                                    cellsSum = cellsSum + double(clickedTable.Data{clickedTable.Selection(kk, 1), clickedTable.Selection(kk, 2)});
                                end
                                cellsAverage = cellsSum/cellsCount;
                        end    
                        tableCountText.Text = sprintf('  CONTAGEM: %d     SOMA: %.2f     MÉDIA: %.2f', cellsCount, cellsSum, cellsAverage);
                    else
                        tableCountText.Text = sprintf('  CONTAGEM: %d', cellsCount);
                    end

                    selectedRows = unique(clickedTable.Selection(:,1));
                    selectedAccountDescription = '';            
                    if isscalar(selectedRows) && ismember('COD_CTA', clickedTable.Data.Properties.VariableNames)
                        selectedECD = selectedECDObject(app);
    
                        selectedAccount = clickedTable.Data.("COD_CTA"){selectedRows};
                        selectedAccountIndex = find(strcmp(selectedECD.Table.mDESCRICAO.("COD_CTA"), selectedAccount), 1);
            
                        if ~isempty(selectedAccountIndex)
                            selectedAccountDescription = sprintf('COD_CTA %s\n%s', selectedAccount, selectedECD.Table.mDESCRICAO.("DESCRIÇÃO"){selectedAccountIndex});
                        end
                    end                    
                    tableSelectedAccount.Text = selectedAccountDescription;
                end
            end

        end

        % Cell edit callback: UITable1, UITable2
        function TableCellEdit(app, event)
            
            % Para contornar BUG do dropdown, que dispara esse evento mesmo
            % quando não há alteração do valor. Além disso, evita alteração
            % do campo quando são apenas inseridos caracteres vazios (no
            % caso de um campo textual).
            previousData = event.PreviousData;
            newData      = event.NewData;
            if (iscategorical(newData) && isequal(previousData, newData)) || ...
               (ischar(newData)        && isequal(strtrim(previousData), strtrim(newData)))
                return
            end            

            % E agora sim analisa a edição da célula...
            rowIndex = event.Indices(1);
            colIndex = event.Indices(2);            

            columnNames = event.Source.Data.Properties.VariableNames;
            editedCellColumnName = columnNames{colIndex};

            selectedECD = selectedECDObject(app);
            
            % Para contornar regra de negócio esquisita do MATLAB, que possibilita
            % criação de nova categoria.
            if strcmp(editedCellColumnName, 'Apurado?  ✎') && ~ismember(newData, app.mainApp.General.ECD.assessmentOptions)
                clickedTable = event.Source;
                clickedTable.Data.(editedCellColumnName)(rowIndex) = selectedECD.Table.mCONTAS.(editedCellColumnName)(rowIndex);
                return
            end

            if iscell(selectedECD.Table.mCONTAS{rowIndex, colIndex})
                newData = {strtrim(newData)};
            end

            selectedECD.Table.mCONTAS{rowIndex, colIndex} = newData;

        end

        % Callback function: FontAlign1, FontAlign2, FontAlign3, 
        % ...and 6 other components
        function TableStyleChanged(app, event)
                        
            clickedTable = onFocusTable(app);
            if isempty(clickedTable.Selection)
                return
            end

            selectedECD = selectedECDObject(app);
            
            switch clickedTable
                case app.UITable1; tableId = app.SheetView_First.Value;
                case app.UITable2; tableId = app.SheetView_Second.Value;
            end

            % Lista atual de estilos:
            renderedTableIDStyleIndex = find(strcmp({selectedECD.GUI.tableView.id}, tableId), 1);
            if isempty(renderedTableIDStyleIndex)
                renderedTableIDStyleIndex = numel(selectedECD.GUI.tableView)+1;
            end
            
            % Estilo novo:
            switch event.Source
                case app.FontFamily
                    fieldName  = 'FontName';
                    fieldValue = {app.FontFamily.Value};
                    app.FontFamily.Value = '';

                case app.FontWeight
                    fieldName  = 'FontWeight';
                    fieldValue = {'bold', 'normal'};

                case app.FontStyle
                    fieldName  = 'FontAngle';
                    fieldValue = {'italic', 'normal'};

                case {app.FontAlign1, app.FontAlign2, app.FontAlign3}
                    fieldName  = 'HorizontalAlignment';
                    switch event.Source
                        case app.FontAlign1; fieldValue = {'left'};
                        case app.FontAlign2; fieldValue = {'center'};
                        case app.FontAlign3; fieldValue = {'right'};
                    end

                case app.FontBackground
                    fieldName  = 'BackgroundColor';
                    fieldValue = {event.Value};
                    app.FontBackground.Value = [1 0 0.0118];

                case app.FontColor
                    fieldName  = 'FontColor';
                    fieldValue = {event.Value};
                    app.FontColor.Value = [0 0 0.0118];

                case app.FontIcon
                    fieldName  = 'Icon';
                    fieldValue = {event.Value};
                    app.FontIcon.Value = '';
            end

            % Verifica se já existe estilo aplicado às células selecionadas:
            styleIndex = find(cellfun(@(x) isequal(clickedTable.Selection, x), clickedTable.StyleConfigurations.TargetIndex));
            if ~isempty(styleIndex)
                styleIndex = styleIndex(end);
                s = clickedTable.StyleConfigurations.Style(styleIndex);                
                removeStyle(clickedTable, styleIndex)
            else
                s = uistyle();
            end

            if ~isempty(s.(fieldName))
                previousValue = s.(fieldName);
                previouValueIndex = find(cellfun(@(x) isequal(x, previousValue), fieldValue));
                s.(fieldName) = fieldValue{setdiff(1:numel(fieldValue), previouValueIndex)};
            else
                s.(fieldName) = fieldValue{1};
            end

            addStyle(clickedTable, s, "cell", clickedTable.Selection)
            if strcmp(app.SheetView_First.Value, app.SheetView_Second.Value)
                otherTable = setdiff([app.UITable1, app.UITable2], clickedTable);
                addStyle(otherTable, s, "cell", clickedTable.Selection)
            end

            selectedECD.GUI.tableView(renderedTableIDStyleIndex).id = tableId;
            selectedECD.GUI.tableView(renderedTableIDStyleIndex).style = clickedTable.StyleConfigurations;
            
        end

        % Image clicked function: StyleDelete, StyleRefresh
        function TableStyleDeleteOrRefresh(app, event)
            
            clickedTable = onFocusTable(app);
            if isempty(clickedTable.Selection)
                return
            end

            selectedECD = selectedECDObject(app);
            
            switch clickedTable
                case app.UITable1; tableId = app.SheetView_First.Value;
                case app.UITable2; tableId = app.SheetView_Second.Value;
            end

            % Lista atual de estilos:
            renderedTableIDStyleIndex = find(strcmp({selectedECD.GUI.tableView.id}, tableId), 1);
            if ~isempty(renderedTableIDStyleIndex)
                switch event.Source
                    case app.StyleDelete
                        styleIndex = find(cellfun(@(x) isequal(clickedTable.Selection, x), clickedTable.StyleConfigurations.TargetIndex));
                        if ~isempty(styleIndex)             
                            removeStyle(clickedTable, styleIndex)
        
                            if strcmp(app.SheetView_First.Value, app.SheetView_Second.Value)
                                otherTable = setdiff([app.UITable1, app.UITable2], clickedTable);
                                removeStyle(otherTable, styleIndex)
                            end
                
                            selectedECD.GUI.tableView(renderedTableIDStyleIndex).id = tableId;
                            selectedECD.GUI.tableView(renderedTableIDStyleIndex).style = clickedTable.StyleConfigurations;
                        end

                    case app.StyleRefresh
                        removeStyle(clickedTable)
                        selectedECD.GUI.tableView(renderedTableIDStyleIndex) = [];

                        if strcmp(app.SheetView_First.Value, app.SheetView_Second.Value)
                            otherTable = setdiff([app.UITable1, app.UITable2], clickedTable);
                            removeStyle(otherTable)
                        end
                end
            end

        end

        % Value changed function: RowHeight
        function TableRowHeightChanged(app, event)

            % ToDo:
            % Inserir comando pra forçar a renderização.

            hTable = onFocusTable(app);
            propertyName = 'height';

            try
                if ~isempty(hTable.Data) &&                            ...
                  (~isprop(hTable, 'StyleObservations')             || ...
                   ~isfield(hTable.StyleObservations, propertyName) || ...
                    isempty(hTable.StyleObservations.(propertyName)))
    
                    hTableName  = ui.CustomizationBase.getPropertyName(hTable, 'ECD');
                    customEvent = struct('auxAppTag',     'ECD',              ...
                                         'componentName', hTableName,         ...
                                         'dataTag',       hTable.UserData.id, ...
                                         'childClass',    'mw-table-row',     ...
                                         'propertyName',  propertyName);

                    sendEventToHTMLSource(app.jsBackDoor, 'getCssPropertyValue', customEvent);
                    matlab.waitfor(hTable, propertyName, @(propName) isprop(hTable, propName), .010, 2, 'propName')
                end
                
                if app.RowHeight.Value
                    defaultProp   = regexp(hTable.StyleObservations.height, '(?<height>\d+[.]?\d*)px', 'names');
                    defaultHeight = str2double(defaultProp.height);

                    sendEventToHTMLSource(app.jsBackDoor, 'changeTableRowHeight', defaultHeight + app.RowHeight.Value);
                else
                    sendEventToHTMLSource(app.jsBackDoor, 'changeTableRowHeight', 'default');
                end

                % A simples troca do foco do elemento força a sua renderização,
                % evitando uso de manipulações diretas em JavaScript.
                pause(.100)
                if app.SheetViewStatus.Value && ~isequal(hTable, app.UITable2)
                    focus(hTable)
                    pause(.100)
                end
                focus(hTable)

            catch ME
                appUtil.modalWindow(app.UIFigure, 'error', ME.message);
            end

        end

        % Value changed function: ColumnWidth
        function TableColumnWidthChanged(app, event)
            
            if ~isempty(app.ColumnWidth.Value)
                hTable = onFocusTable(app);

                if isequal(hTable.ColumnWidth, app.ColumnWidth.Value)
                    widthOptions = setdiff(app.ColumnWidth.Items, {'', app.ColumnWidth.Value});
                    hTable.ColumnWidth = widthOptions{1};
                    drawnow
                end

                hTable.ColumnWidth = app.ColumnWidth.Value;
                app.ColumnWidth.Value = '';
            end
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'monitorSPED';
            app.UIFigure.Icon = 'icon_16.png';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @closeFcn, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.toolGrid = uigridlayout(app.GridLayout);
            app.tool_CompanyInfo = uilabel(app.toolGrid);
            app.tool_CompanyInfo.Interpreter = 'html';
            app.tool_CompanyInfo.Text = {'<font style="font-size: 11px; font-weight: bold;">NOME DA EMPRESA</font> CNPJ 10.101.101/0001-02 '; '01/01/2023 - 31/12/2023 '};
            app.tool_GenerateReport = uiimage(app.toolGrid);
            app.tool_GenerateReport.ImageClickedFcn = createCallbackFcn(app, @Toolbar_ReportImageClicked, true);
            app.tool_GenerateReport.Tooltip = {'Gera relatório análise'};
            app.tool_GenerateReport.ImageSource = 'Publish_HTML_16.png';
            app.tool_UploadFinalFile = uiimage(app.toolGrid);
            app.tool_UploadFinalFile.ImageSource = 'Up_24.png';
            app.UITable1 = uitable(app.GridLayout);
            app.UITable1.ColumnName = '';
            app.UITable1.ColumnSortable = true;
            app.UITable1.CellEditCallback = createCallbackFcn(app, @TableCellEdit, true);
            app.UITable1.ClickedFcn = createCallbackFcn(app, @TableClicked, true);
            app.UITable1.KeyReleaseFcn = createCallbackFcn(app, @TableClicked, true);
            app.UITable1_CountIcon = uiimage(app.GridLayout);
            app.UITable1_CountIcon.ImageSource = 'selectColumn.png';
            app.UITable1_CountText = uilabel(app.GridLayout);
            app.UITable1_CountText.Text = ' CONTAGEM : 0';
            app.UITable1_FilterText = uilabel(app.GridLayout);
            app.UITable1_FilterText.Text = '0 DE 0 ';
            app.UITable1_AccountInfo = uilabel(app.GridLayout);
            app.UITable1_AccountInfo.Text = '';
            app.UITable2 = uitable(app.GridLayout);
            app.UITable2.ColumnName = '';
            app.UITable2.RowName = {};
            app.UITable2.ColumnSortable = true;
            app.UITable2.CellEditCallback = createCallbackFcn(app, @TableCellEdit, true);
            app.UITable2.ClickedFcn = createCallbackFcn(app, @TableClicked, true);
            app.UITable2.Visible = 'off';
            app.UITable2.KeyReleaseFcn = createCallbackFcn(app, @TableClicked, true);
            app.UITable2_CountIcon = uiimage(app.GridLayout);
            app.UITable2_CountIcon.ImageSource = 'selectColumn.png';
            app.UITable2_CountText = uilabel(app.GridLayout);
            app.UITable2_CountText.Text = ' CONTAGEM: 0';
            app.UITable2_FilterText = uilabel(app.GridLayout);
            app.UITable2_FilterText.Text = '0 DE 0';
            app.UITable2_AccountInfo = uilabel(app.GridLayout);
            app.UITable2_AccountInfo.Text = '';
            app.TabGroup = uitabgroup(app.GridLayout);
            app.TabGroup.SelectionChangedFcn = createCallbackFcn(app, @TabGroupSelectionChanged, true);
            app.Tab1 = uitab(app.TabGroup);
            app.Tab1.Title = 'ASPECTOS GERAIS';
            app.Tab1Grid = uigridlayout(app.Tab1);
            app.CompanyNameListLabel = uilabel(app.Tab1Grid);
            app.CompanyNameListLabel.Text = 'EMPRESA:';
            app.CompanyNameList = uidropdown(app.Tab1Grid);
            app.CompanyNameList.Items = {};
            app.CompanyNameList.ValueChangedFcn = createCallbackFcn(app, @CompanyNameListValueChanged, true);
            app.CompanyNameList.Value = {};
            app.TimePeriodListLabel = uilabel(app.Tab1Grid);
            app.TimePeriodListLabel.Text = 'PERÍODO FISCAL:';
            app.TimePeriodList = uidropdown(app.Tab1Grid);
            app.TimePeriodList.Items = {};
            app.TimePeriodList.ValueChangedFcn = createCallbackFcn(app, @TimePeriodListValueChanged, true);
            app.TimePeriodList.Value = {};
            app.SheetListLabel = uilabel(app.Tab1Grid);
            app.SheetListLabel.Text = 'REGISTRO:';
            app.SheetList = uidropdown(app.Tab1Grid);
            app.SheetList.Items = {};
            app.SheetList.ValueChangedFcn = createCallbackFcn(app, @SheetViewFirstValueChanged, true);
            app.SheetList.Value = {};
            app.Tab1Separator = uiimage(app.Tab1Grid);
            app.Tab1Separator.Enable = 'off';
            app.Tab1Separator.ImageSource = 'LineV.svg';
            app.ReadProcessLog = uiimage(app.Tab1Grid);
            app.ReadProcessLog.ImageClickedFcn = createCallbackFcn(app, @Toolbar_LOGInfoImageClicked, true);
            app.ReadProcessLog.Tooltip = {'LOG do processo de leitura dos registros ordinários'};
            app.ReadProcessLog.ImageSource = 'LOG_32.png';
            app.OpenRTFFiles = uiimage(app.Tab1Grid);
            app.OpenRTFFiles.ImageClickedFcn = createCallbackFcn(app, @Toolbar_OpenRTFImageClicked, true);
            app.OpenRTFFiles.Tooltip = {'Abre/Salva arquivos .rtf'; '(Registros J800 e J801)'};
            app.OpenRTFFiles.ImageSource = 'Publish_PDF_16.png';
            app.ExportExcel = uibutton(app.Tab1Grid, 'push');
            app.ExportExcel.ButtonPushedFcn = createCallbackFcn(app, @Toolbar_ExportImageClicked, true);
            app.ExportExcel.Icon = 'Export_24.png';
            app.ExportExcel.Tooltip = {'Exporta registros como arquivo .xlsx'};
            app.ExportExcel.Text = '';
            app.MiscellaneousLabel = uilabel(app.Tab1Grid);
            app.MiscellaneousLabel.Text = 'MISCELÂNEAS';
            app.Tab2 = uitab(app.TabGroup);
            app.Tab2.Title = 'LAYOUT';
            app.Tab2Grid = uigridlayout(app.Tab2);
            app.SheetViewStatus = uibutton(app.Tab2Grid, 'state');
            app.SheetViewStatus.ValueChangedFcn = createCallbackFcn(app, @SheetViewStatusValueChanged, true);
            app.SheetViewStatus.Icon = 'split_top_bottom_ts_24-a602190eb092f2373c13f20ec5875137.png';
            app.SheetViewStatus.Text = 'TELA';
            app.SheetView_First = uidropdown(app.Tab2Grid);
            app.SheetView_First.Items = {};
            app.SheetView_First.ValueChangedFcn = createCallbackFcn(app, @SheetViewFirstValueChanged, true);
            app.SheetView_First.Value = {};
            app.SheetHeight_First = uispinner(app.Tab2Grid);
            app.SheetHeight_First.Limits = [1 5];
            app.SheetHeight_First.RoundFractionalValues = 'on';
            app.SheetHeight_First.ValueDisplayFormat = '%d';
            app.SheetHeight_First.ValueChangedFcn = createCallbackFcn(app, @SheetViewHeightValueChanged, true);
            app.SheetHeight_First.Enable = 'off';
            app.SheetHeight_First.Value = 1;
            app.SheetView_Second = uidropdown(app.Tab2Grid);
            app.SheetView_Second.Items = {};
            app.SheetView_Second.ValueChangedFcn = createCallbackFcn(app, @SheetViewSecondValueChanged, true);
            app.SheetView_Second.Enable = 'off';
            app.SheetView_Second.Value = {};
            app.SheetHeight_Second = uispinner(app.Tab2Grid);
            app.SheetHeight_Second.Limits = [0 5];
            app.SheetHeight_Second.RoundFractionalValues = 'on';
            app.SheetHeight_Second.ValueDisplayFormat = '%d';
            app.SheetHeight_Second.ValueChangedFcn = createCallbackFcn(app, @SheetViewHeightValueChanged, true);
            app.SheetHeight_Second.Enable = 'off';
            app.SheetOnFocus = uilamp(app.Tab2Grid);
            app.SheetOnFocus.Tooltip = {'Tabela em evidência'};
            app.Tab2Separator1 = uiimage(app.Tab2Grid);
            app.Tab2Separator1.Enable = 'off';
            app.Tab2Separator1.ImageSource = 'LineV.svg';
            app.RowHeight = uispinner(app.Tab2Grid);
            app.RowHeight.Step = 5;
            app.RowHeight.Limits = [0 50];
            app.RowHeight.RoundFractionalValues = 'on';
            app.RowHeight.ValueDisplayFormat = '%d';
            app.RowHeight.ValueChangedFcn = createCallbackFcn(app, @TableRowHeightChanged, true);
            app.RowHeightLabel = uilabel(app.Tab2Grid);
            app.RowHeightLabel.Text = {'ALTURA LINHA'; '(offset)'};
            app.ColumnWidth = uidropdown(app.Tab2Grid);
            app.ColumnWidth.Items = {'', 'auto', 'fit', '1x'};
            app.ColumnWidth.ValueChangedFcn = createCallbackFcn(app, @TableColumnWidthChanged, true);
            app.ColumnWidth.Value = '';
            app.ColumnWidthLabel = uilabel(app.Tab2Grid);
            app.ColumnWidthLabel.Text = {'LARGURA'; 'COLUNA'};
            app.Tab2Separator2 = uiimage(app.Tab2Grid);
            app.Tab2Separator2.Enable = 'off';
            app.Tab2Separator2.ImageSource = 'LineV.svg';
            app.FontFamily = uidropdown(app.Tab2Grid);
            app.FontFamily.Items = {};
            app.FontFamily.ValueChangedFcn = createCallbackFcn(app, @TableStyleChanged, true);
            app.FontFamily.Tooltip = {'Fonte'};
            app.FontFamily.Value = {};
            app.FontWeight = uibutton(app.Tab2Grid, 'push');
            app.FontWeight.ButtonPushedFcn = createCallbackFcn(app, @TableStyleChanged, true);
            app.FontWeight.Text = 'B';
            app.FontStyle = uibutton(app.Tab2Grid, 'push');
            app.FontStyle.ButtonPushedFcn = createCallbackFcn(app, @TableStyleChanged, true);
            app.FontStyle.Text = 'I ';
            app.FontAlign1 = uiimage(app.Tab2Grid);
            app.FontAlign1.ImageClickedFcn = createCallbackFcn(app, @TableStyleChanged, true);
            app.FontAlign1.Tooltip = {'Sublinhado'};
            app.FontAlign1.ImageSource = 'AlignedLeft_16-7f46662cd6fd7221119660e14bdcea56.png';
            app.FontAlign2 = uiimage(app.Tab2Grid);
            app.FontAlign2.ImageClickedFcn = createCallbackFcn(app, @TableStyleChanged, true);
            app.FontAlign2.Tooltip = {'Sublinhado'};
            app.FontAlign2.ImageSource = 'AlignedCenter_16-b91485db227234029c43b7823c09ebff.png';
            app.FontAlign3 = uiimage(app.Tab2Grid);
            app.FontAlign3.ImageClickedFcn = createCallbackFcn(app, @TableStyleChanged, true);
            app.FontAlign3.Tooltip = {'Sublinhado'};
            app.FontAlign3.ImageSource = 'AlignedRight_16-7827788943408c9bac98181b7ad0efb5.png';
            app.FontBackground = uicolorpicker(app.Tab2Grid);
            app.FontBackground.Value = [1 0 0.0118];
            app.FontBackground.Icon = 'fill';
            app.FontBackground.ValueChangedFcn = createCallbackFcn(app, @TableStyleChanged, true);
            app.FontColor = uicolorpicker(app.Tab2Grid);
            app.FontColor.Value = [0 0 0.0118];
            app.FontColor.Icon = 'text';
            app.FontColor.ValueChangedFcn = createCallbackFcn(app, @TableStyleChanged, true);
            app.Tab2Separator3 = uiimage(app.Tab2Grid);
            app.Tab2Separator3.Enable = 'off';
            app.Tab2Separator3.ImageSource = 'LineV.svg';
            app.FontIcon = uidropdown(app.Tab2Grid);
            app.FontIcon.Items = {'', 'question', 'info', 'success', 'warning', 'error', 'none'};
            app.FontIcon.ValueChangedFcn = createCallbackFcn(app, @TableStyleChanged, true);
            app.FontIcon.Value = '';
            app.FontIconLabel = uilabel(app.Tab2Grid);
            app.FontIconLabel.Text = 'ÍCONE';
            app.StyleDelete = uiimage(app.Tab2Grid);
            app.StyleDelete.ImageClickedFcn = createCallbackFcn(app, @TableStyleDeleteOrRefresh, true);
            app.StyleDelete.Tooltip = {'Exclui estilo relacionado às células selecionadas'};
            app.StyleDelete.ImageSource = 'delete.svg';
            app.StyleRefresh = uiimage(app.Tab2Grid);
            app.StyleRefresh.ImageClickedFcn = createCallbackFcn(app, @TableStyleDeleteOrRefresh, true);
            app.StyleRefresh.Tooltip = {'Retorna às configurações iniciais de estilo'};
            app.StyleRefresh.ImageSource = 'Refresh_18.png';
            app.dockModuleGrid = uigridlayout(app.GridLayout);
            app.dockModuleGrid.Visible = 'off';
            app.dockModule_Close = uiimage(app.dockModuleGrid);
            app.dockModule_Close.ImageClickedFcn = createCallbackFcn(app, @DockModuleGroup_ButtonPushed, true);
            app.dockModule_Close.Tag = 'DRIVETEST';
            app.dockModule_Close.Tooltip = {'Fecha módulo'};
            app.dockModule_Close.ImageSource = 'Delete_12SVG_white.svg';
            app.dockModule_Undock = uiimage(app.dockModuleGrid);
            app.dockModule_Undock.ImageClickedFcn = createCallbackFcn(app, @DockModuleGroup_ButtonPushed, true);
            app.dockModule_Undock.Tag = 'DRIVETEST';
            app.dockModule_Undock.Enable = 'off';
            app.dockModule_Undock.Tooltip = {'Reabre módulo em outra janela'};
            app.dockModule_Undock.ImageSource = 'Undock_18White.png';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = winECD(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end